classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadImageButton            matlab.ui.control.Button
        ApplyLowPassFilterButton   matlab.ui.control.Button
        ApplyHighPassFilterButton  matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxesOriginal             matlab.ui.control.UIAxes
        UIAxesLowPassFiltered      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        OriginalImage % Исходное изображение
        FilteredImage % Изображение после низкочастотной фильтрации
        FilteredImageHigh % Изображение после высокочастотной фильтрации
        GrayscaleImage
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.tif'}, 'Выберите изображение');
            if isequal(file, 0)
                return;
            end
            app.OriginalImage = imread(fullfile(path, file));
            app.GrayscaleImage = rgb2gray(app.OriginalImage);
            imshow(app.OriginalImage, 'Parent', app.UIAxesOriginal);
            app.UIAxesOriginal.Title.String = 'Исходное';
        end

        % Button pushed function: ApplyLowPassFilterButton
        function ApplyLowPassFilterButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Сначала загрузите изображение', 'Ошибка');
                return;
            end

            % Преобразование изображения в частотное пространство
            fft_image = fft2(double(app.OriginalImage));
            shifted_fft_image = fftshift(fft_image);

            % Определение радиуса фильтра
            radius = 0.2 * min(size(app.OriginalImage, 1), size(app.OriginalImage, 2));

            % Создание низкочастотного фильтра
            [x, y] = meshgrid(1:size(app.OriginalImage, 2), 1:size(app.OriginalImage, 1));
            center_x = size(app.OriginalImage, 2) / 2;
            center_y = size(app.OriginalImage, 1) / 2;
            low_pass_filter = sqrt((x - center_x).^2 + (y - center_y).^2) <= radius;

            % Применение низкочастотного фильтра
            filtered_low_fft_image = shifted_fft_image .* low_pass_filter;

            % Обратное преобразование для низкочастотного фильтра
            app.FilteredImage = ifft2(ifftshift(filtered_low_fft_image));

            % Отображение результата низкочастотного фильтра
            imshow(uint8(abs(app.FilteredImage)), 'Parent', app.UIAxesLowPassFiltered);
            app.UIAxesLowPassFiltered.Title.String = 'Низкочастотный';
        end

        % Button pushed function: ApplyHighPassFilterButton
        function ApplyHighPassFilterButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Сначала загрузите изображение', 'Ошибка');
                return;
            end

            % Преобразование изображения в частотное пространство
            fft_image = fft2(double(app.GrayscaleImage));
            shifted_fft_image = fftshift(fft_image);

            % Определение радиуса фильтра
            radius = 0.007 * min(size(app.GrayscaleImage, 1), size(app.GrayscaleImage, 2));

            % Создание высокочастотного фильтра
            [x, y] = meshgrid(1:size(app.GrayscaleImage, 2), 1:size(app.GrayscaleImage, 1));
            center_x = size(app.GrayscaleImage, 2) / 2;
            center_y = size(app.GrayscaleImage, 1) / 2;
            high_pass_filter = sqrt((x - center_x).^2 + (y - center_y).^2) > radius;

            % Применение высокочастотного фильтра
            filtered_high_fft_image = shifted_fft_image .* high_pass_filter;

            % Обратное преобразование для высокочастотного фильтра
            app.FilteredImageHigh  = ifft2(ifftshift(filtered_high_fft_image));

            % Отображение результата высокочастотного фильтра
            imshow(uint8(abs(app.FilteredImageHigh)), 'Parent', app.UIAxes);
            app.UIAxes.Title.String = 'Высокочастотный';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesLowPassFiltered = uiaxes(app.UIFigure);
            app.UIAxesLowPassFiltered.XTick = [];
            app.UIAxesLowPassFiltered.YTick = [];
            app.UIAxesLowPassFiltered.ZTick = [];
            app.UIAxesLowPassFiltered.Visible = 'off';
            app.UIAxesOriginal = uiaxes(app.UIFigure);
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesOriginal.ZTick = [];
            app.UIAxesOriginal.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.ApplyHighPassFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyHighPassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyHighPassFilterButtonPushed, true);
            app.ApplyHighPassFilterButton.Icon = fullfile(pathToMLAPP, 'ic3.png');
            app.ApplyHighPassFilterButton.Text = '';
            app.ApplyLowPassFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyLowPassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLowPassFilterButtonPushed, true);
            app.ApplyLowPassFilterButton.Icon = fullfile(pathToMLAPP, 'ic3.png');
            app.ApplyLowPassFilterButton.Text = '';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = fullfile(pathToMLAPP, 'img.png');
            app.LoadImageButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end